classdef track_anal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        btnTrajectories        matlab.ui.control.Button
        btnGSEROpt             matlab.ui.control.Button
        lblStatus              matlab.ui.control.Label
        TrackfilesmatLabel     matlab.ui.control.Label
        SaveButton             matlab.ui.control.Button
        btnRemove              matlab.ui.control.Button
        btnLucy                matlab.ui.control.Button
        sldLagTime             matlab.ui.control.Slider
        LagtimesLabel          matlab.ui.control.Label
        bgpPlotData            matlab.ui.container.ButtonGroup
        btnChi_4               matlab.ui.control.Button
        optChi4                matlab.ui.control.RadioButton
        optVanHove             matlab.ui.control.RadioButton
        optAlpha_2             matlab.ui.control.RadioButton
        optGSER                matlab.ui.control.RadioButton
        optMPMSD               matlab.ui.control.RadioButton
        btnCalculate           matlab.ui.control.Button
        tblCurrentTracks       matlab.ui.control.Table
        btnAddTrack            matlab.ui.control.Button
        pnlParameters          matlab.ui.container.Panel
        edtResolution          matlab.ui.control.NumericEditField
        ResolutionmpixelLabel  matlab.ui.control.Label
        edtShutterTime         matlab.ui.control.NumericEditField
        ShuttertimesLabel      matlab.ui.control.Label
        edtParticleRadius      matlab.ui.control.NumericEditField
        ParticleradiusmLabel   matlab.ui.control.Label
        edtTemperature         matlab.ui.control.NumericEditField
        TemperatureCLabel      matlab.ui.control.Label
        axePlot                matlab.ui.control.UIAxes
    end


    properties (Access = private)
        CurrentPath=pwd % Description
        msd_i
        msd
        G
        tracks
        alpha_2
        alpha_2_i
        van_Hove
        p_D
        P_D
        van_Hove_rec=[]
        dtP
        CurrentSelectedRow % Description
        GSER_dialogBox
        Lucy_dialogBox
        isParallelAvailable
        chi4_s=[]
        chi4_d=[]
        chi4_t=[]
        Q_s=[]
        Q_d=[]
        Q_t=[]
        blnTrackExcluded
        excludedTrackID % Description
        traj_dialogBox % Description
    end

    methods (Access = public)

        function GSER_result(app,G)

            app.G=G;
            if app.bgpPlotData.SelectedObject==app.optGSER
                cla(app.axePlot,'reset');

                app.axePlot.Box='on';

                loglog(app.axePlot,app.G(:,1),app.G(:,2:3),'LineWidth',1);

                app.axePlot.XLabel.String='angular frequency (rad/s)';
                app.axePlot.YLabel.String='Moduli (Pa)';
                app.axePlot.XLim=10.^[floor(log10(min(app.G(:,1)))),ceil(log10(max(app.G(:,1))))];
                app.axePlot.YLim=10.^[floor(log10(min([app.G(app.G(:,2)>0,2);app.G(app.G(:,3)>0,3)]))),...
                    ceil(log10(max([app.G(app.G(:,2)>0,2);app.G(app.G(:,3)>0,3)])))];
                app.axePlot.XTick=10.^(floor(log10(min(app.G(:,1)))):ceil(log10(max(app.G(:,1)))));
                app.axePlot.YTick=10.^(floor(log10(min([app.G(app.G(:,2)>0,2);app.G(app.G(:,3)>0,3)]))):...
                    ceil(log10(max([app.G(app.G(:,2)>0,2);app.G(app.G(:,3)>0,3)]))));
                app.axePlot.XMinorTick='on';
                app.axePlot.YMinorTick='on';

                lg=legend(app.axePlot);

                lg.Interpreter='latex';
                lg.String=[{'$G\prime$'},{'$G\prime\prime$'}];
                lg.Location='best';
                lg.Color='none';


            end
        end

        function Lucy_result(app,p_D,P_D,van_Hove_rec,dtP)
            if ~isempty(p_D)
                app.p_D=p_D;
                app.P_D=P_D;
                app.van_Hove_rec=van_Hove_rec;
                app.dtP=dtP;

                app.sldLagTime.MinorTicks=log10(dtP);
                if app.bgpPlotData.SelectedObject==app.optVanHove
                    dt=10.^app.sldLagTime.Value;
                    [~,ind]=min(abs(app.msd(:,1)-dt));

                    reset(app.axePlot)
                    app.axePlot.Box='on';
                    app.axePlot.XMinorTick='on';
                    app.axePlot.YMinorTick='on';

                    legend(app.axePlot,'off');
                    N=app.van_Hove{ind,1};
                    edges=app.van_Hove{ind,2};
                    bins=edges(1:end-1)+diff(edges)./2;

                    plot(app.axePlot,bins,N,'.','LineWidth',1);
                    app.axePlot.XScale='linear';
                    app.axePlot.YScale='log';
                    hold(app.axePlot)
                    plot(app.axePlot,bins,exp(-bins.^2./(2.*app.msd(ind,2)))./sqrt(2.*pi.*app.msd(ind,2)),'-','LineWidth',1,'Color',[0.8500 0.3250 0.0980])
                    hold(app.axePlot)

                    [~,ind2]=min(abs(app.msd(:,1)-app.dtP));
                    if ~isempty(app.van_Hove_rec)& ind2==ind
                        hold(app.axePlot,'on');
                        plot(app.axePlot,app.van_Hove_rec(:,1),app.van_Hove_rec(:,2),'-','LineWidth',1,'Color',[0.9290 0.6940 0.1250])
                        hold(app.axePlot,'off');
                    end


                    app.axePlot.XLim=[-10.^max(log10(abs(bins))),10.^max(log10(abs(bins)))];
                    app.axePlot.YLim=10.^[floor(log10(min(N(N>0)))),ceil(log10(max(N)))];
                    app.axePlot.YTick=10.^(floor(log10(min(N(N>0)))):ceil(log10(max(N))));
                    app.axePlot.Title.String='van Hove';
                    app.axePlot.XLabel.Interpreter='tex';
                    app.axePlot.XLabel.String='\Delta\itx\rm (m)';
                    app.axePlot.YLabel.Interpreter='tex';
                    app.axePlot.YLabel.String='prob. density (-)';


                    tx=text(app.axePlot,app.axePlot.XLim(1),app.axePlot.YLim(2),[' Δ\itt\rm = ' num2str(app.msd(ind,1),'%.3g') ' s']);
                    tx.VerticalAlignment='top';
                end

            end
        end

        function results = traj_result(app,excludedID)
            excludedID=str2double(excludedID);
            excludedID=excludedID(:);
            app.excludedTrackID=excludedID;
            app.blnTrackExcluded=true;
            app.btnTrajectories.Enable='on';

        end
    end

    methods (Access = private)

        function corrected_tracks = drift_canceling(app,tracks)
            % calculate the displacements between adjacent frames, and label them by
            % staring_fr_id's and tr_id's. [dx,dy,starting_fr_id,tr_id]
            drift=cellfun(@(x) [diff(x(:,1:3),1,1),x(1:end-1,3:4)],tracks,'UniformOutput',0);
            drift=cellfun(@(x) x(x(:,3)==1,[1,2,4,5]),drift,'UniformOutput',0);

            % group drift_temp into cell array by starting_fr_id's, and average the
            % dispacements of each starting_fr_id. [mean_dx,mean_dy, starting_fr_id]
            drift=sortrows(cell2mat(drift),[3,4]);
            ind=[[1;find(diff(drift(:,3))~=0)+1],[find(diff(drift(:,3))~=0);length(drift(:,3))]];
            drift=cell2mat(arrayfun(@(i,j) [mean(drift(i:j,1:2),1),drift(i,3)],ind(:,1),ind(:,2),'UniformOutput',0));

            % zero-padding missing starting_fr_id by zero displacements
            padded_drift=zeros(max(drift(:,3))-min(drift(:,3))+1,2);
            step_ids=unique(drift(:,3));
            for i=1:length(step_ids)
                step_id=step_ids(i);
                idx=drift(:,3)==step_id;
                padded_drift(step_id-min(drift(:,3))+1,:)=drift(idx,1:2);
            end
            drift=[padded_drift,(min(drift(:,3)):max(drift(:,3)))'];
            drift(:,1:2)=cumsum(drift(:,1:2),1);
            drift=[[zeros(1,2),min(drift(:,3))-1];drift];
            % now drift can serve as the drift profile
            % drift_profile=drift;

            % If we need to apply corrction using drift
            corrected_tracks=cell(size(tracks));
            for i=1:length(tracks)
                t=tracks{i};
                start_step=t(1,3);
                for j=2:size(t,1)

                    step_id=t(j,3);
                    rel_drift=drift(step_id-min(drift(:,3)),1:2)-drift(start_step-min(drift(:,3)),1:2);
                    t(j,1:2)=t(j,1:2)-rel_drift;
                end

                corrected_tracks{i}=t;
            end

        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Check if parallel computing is ready
            v=ver;
            isParallelToolboxInstalled=any(strcmp({v.Name},'Parallel Computing Toolbox'));
            if isParallelToolboxInstalled

                defaultProfile=parallel.defaultClusterProfile;
                if ~isempty(defaultProfile)

                    cluster=parcluster(defaultProfile);

                    try
                        job=createJob(cluster);
                        delete(job);
                        app.isParallelAvailable=true;
                    catch
                        app.isParallelAvailable=false;
                    end
                else
                    app.isParallelAvailable=false;
                end
            else
                app.isParallelAvailable=false;
            end


            % Enable chi_4 calculation if parallel computing is ready
            if app.isParallelAvailable
                app.btnChi_4.Enable='on';
                app.optChi4.Enable='on';
            end
        end

        % Value changed function: edtResolution
        function edtResolutionValueChanged(app, event)
            value = app.edtResolution.Value;
            if isnan(value)||value<=0
                % errordlg('Please enter a positive, numeric value.', 'Bad input', 'modal')
                uialert(app.UIFigure,'Please enter a positive, numeric value.', 'Bad input','Modal',true,'Icon','error')
                app.edtResolution.Value=0.1083266701;
                return
            end
        end

        % Value changed function: edtTemperature
        function edtTemperatureValueChanged(app, event)
            value = app.edtTemperature.Value;
            if isnan(value)||value<=0
                % errordlg('Please enter a positive, numeric value.', 'Bad input', 'modal')
                uialert(app.UIFigure,'Please enter a positive, numeric value.', 'Bad input','Modal',true,'Icon','error')

                app.edtTemperature.Value=30;
                return
            end
        end

        % Value changed function: edtParticleRadius
        function edtParticleRadiusValueChanged(app, event)
            value = app.edtParticleRadius.Value;
            if isnan(value)||value<=0
                % errordlg('Please enter a positive, numeric value.', 'Bad input', 'modal')
                uialert(app.UIFigure,'Please enter a positive, numeric value.', 'Bad input','Modal',true,'Icon','error')
                app.edtParticleRadius.Value=0.1;
                return
            end
        end

        % Value changed function: edtShutterTime
        function edtShutterTimeValueChanged(app, event)
            value = app.edtShutterTime.Value;
            if isnan(value)||value<=0
                % errordlg('Please enter a positive, numeric value.', 'Bad input', 'modal')
                uialert(app.UIFigure,'Please enter a positive, numeric value.', 'Bad input','Modal',true,'Icon','error')
                app.edtShutterTime.Value=0.020142000000000;
                return
            end
        end

        % Button pushed function: btnAddTrack
        function btnAddTrackButtonPushed(app, event)
            [file,path]=uigetfile('*.mat','Select tr.mat file',app.CurrentPath,'MultiSelect','on');


            % Convert the warning event into error that can be trapped by a
            % try-catch cycle.
            % More about the 'error' state of warning function see:
            % https://tinyurl.com/4ekrcdu6
            % warning('error','MATLAB:load:variableNotFound');

            switch class(file)
                case 'char' % a single file is selected
                    blnSuccess=true;
                    % set the initial state as true and let subsequent errors
                    % change it into false

                    % get a list of variables
                    variable_list=who('-file',fullfile(path,file));

                    % check exisitence of tr
                    if ~isempty(find(ismember(variable_list,'tr'), 1))
                        tr=load(fullfile(path,file),'tr');

                    else
                        blnSuccess=false;
                        uialert(app.UIFigure,'No track data in this file.','Icon','error','Modal',true);
                        figure(app.UIFigure);
                    end

                    if blnSuccess

                        t=app.tblCurrentTracks.Data;
                        tr=tr.tr;
                        tr=sortrows(tr,[4,3]);

                        N_tracks=tr(end,4);

                        if isempty(t)
                            t={path,file,num2str(N_tracks)};
                            app.tblCurrentTracks.ColumnName={'Path';'File name';'# tracks'};

                        else
                            t=[t;{path,file,num2str(N_tracks)}];

                            % check duplicated opened file
                            dup=t;
                            [~,idx]=unique(strcat(dup(:,1),dup(:,2),dup(:,3)));
                            t=dup(idx,:);
                        end
                        app.tblCurrentTracks.Data=t;
                        app.CurrentPath=path;
                        app.blnTrackExcluded=false;


                    end

                    % load other parameters if exists
                    if ~isempty(find(ismember(variable_list,'dt'), 1))
                        dt=load(fullfile(path,file),'dt');
                        if ~isempty(dt.dt)
                            app.edtShutterTime.Value=dt.dt;
                        end
                    end

                    if ~isempty(find(ismember(variable_list,'PixelSize'), 1))

                        PixelSize=load(fullfile(path,file),'PixelSize');
                        if ~isempty(PixelSize.PixelSize)
                            app.edtResolution.Value=PixelSize.PixelSize;
                        end
                    end


                case 'cell' % multiple files are selected

                    no_track_list=[];
                    for i=1:length(file)
                        blnSuccess=true;
                        variable_list=who('-file',fullfile(path,file{i}));

                        if ~isempty(find(ismember(variable_list,'tr'), 1))
                            tr=load(fullfile(path,file{i}),'tr');
                        else
                            blnSuccess=false;
                            no_track_list=[no_track_list;fullfile(path,file{i})];
                        end

                        if blnSuccess

                            t=app.tblCurrentTracks.Data;
                            tr=tr.tr;
                            tr=sortrows(tr,[4,3]);

                            N_tracks=tr(end,4);

                            if isempty(t)
                                t={path,file{i},num2str(N_tracks)};
                                app.tblCurrentTracks.ColumnName={'Path';'File name';'# tracks'};

                            else
                                t=[t;{path,file{i},num2str(N_tracks)}];

                                % check duplicated opened file
                                dup=t;
                                [~,idx]=unique(strcat(dup(:,1),dup(:,2),dup(:,3)));
                                t=dup(idx,:);
                            end
                            app.tblCurrentTracks.Data=t;
                            app.blnTrackExcluded=false;

                        end
                        app.CurrentPath=path;

                        % load other parameters if exists
                        if ~isempty(find(ismember(variable_list,'dt'), 1))
                            dt=load(fullfile(path,file{i}),'dt');
                            app.edtShutterTime.Value=dt.dt;
                        end

                        if ~isempty(find(ismember(variable_list,'PixelSize'), 1))
                            PixelSize=load(fullfile(path,file{i}),'PixelSize');
                            if ~isempty(PixelSize.PixelSize)

                                app.edtResolution.Value=PixelSize.PixelSize;
                            end
                        end


                    end
                    if ~isempty(no_track_list)
                        message=["No track data in file(s):";no_track_list];
                        uialert(app.UIFigure,message,'Error','Icon','error','Modal',true);
                        figure(app.UIFigure);
                    end
                case 'double' % no file is selected
                    uialert(app.UIFigure,'No file selected.','Canceled','Icon','info','Modal',true);
                    figure(app.UIFigure)
                    blnSuccess=false;
                    % app.CurrentPath=path;
            end


            % switch class(file)
            %     case 'char' % single file selected
            %
            %         blnSuccess=true;
            %         % set the initial state as true and let subsequent errors
            %         % change it into false
            %
            %         % see if the opened .mat file contains the variable tr
            %         try
            %             tr=load([path file],'tr');
            %         catch ME
            %             uialert(app.UIFigure,ME.message,'Error','Icon','error')
            %             blnSuccess=false;
            %         end
            %         % Set the state of warning event back to normal
            %         warning('on','MATLAB:load:variableNotFound');
            %
            %         % update tblCurrentTracks
            %         if blnSuccess
            %
            %             t=app.tblCurrentTracks.Data;
            %             tr=tr.tr;
            %             tr=sortrows(tr,[4,3]);
            %
            %             N_tracks=tr(end,4);
            %
            %             if isempty(t)
            %                 t={path,file,num2str(N_tracks)};
            %                 app.tblCurrentTracks.ColumnName={'Path';'File name';'# tracks'};
            %
            %             else
            %                 t=[t;{path,file,num2str(N_tracks)}];
            %
            %                 % check duplicated opened file
            %                 dup=t;
            %                 [~,idx]=unique(strcat(dup(:,1),dup(:,2),dup(:,3)));
            %                 t=dup(idx,:);
            %             end
            %             app.tblCurrentTracks.Data=t;
            %             app.CurrentPath=path;
            %         end
            %
            %     case 'cell' % multiple files selected
            %         for i=1:length(file)
            %             % do the same as the case for single file selected
            %
            %             % see if the opened .mat file contains the variable tr
            %             blnSuccess=true;
            %             try
            %                 tr=load(fullfile(path,file{i}),'tr');
            %             catch ME
            %                 uialert(app.UIFigure,ME.message,'Error','Icon','error')
            %                 blnSuccess=false;
            %             end
            %             % Set the state of warning event back to normal
            %             warning('on','MATLAB:load:variableNotFound');
            %
            %             % update tblCurrentTracks
            %             if blnSuccess
            %
            %                 t=app.tblCurrentTracks.Data;
            %                 tr=tr.tr;
            %                 tr=sortrows(tr,[4,3]);
            %
            %                 N_tracks=tr(end,4);
            %
            %                 if isempty(t)
            %                     t={path,file{i},num2str(N_tracks)};
            %                     app.tblCurrentTracks.ColumnName={'Path';'File name';'# tracks'};
            %
            %                 else
            %                     t=[t;{path,file{i},num2str(N_tracks)}];
            %
            %                     % check duplicated opened file
            %                     dup=t;
            %                     [~,idx]=unique(strcat(dup(:,1),dup(:,2),dup(:,3)));
            %                     t=dup(idx,:);
            %                 end
            %                 app.tblCurrentTracks.Data=t;
            %             end
            %             app.CurrentPath=path;
            %         end
            %     case 'double' % no file selected
            %         uialert(app.UIFigure,'No track selected.','Canceled','Icon','info');
            %         blnSuccess=false;
            %         warning('on','MATLAB:load:variableNotFound');
            % end

            figure(app.UIFigure);


        end

        % Button pushed function: btnCalculate
        function btnCalculateButtonPushed(app, event)
            % allocate final data variables
            app.msd=[];
            app.msd_i=[];
            app.G=[];
            app.alpha_2=[];
            app.alpha_2_i=[];
            app.van_Hove=[];
            app.p_D=[];
            app.P_D=[];
            app.van_Hove_rec=[];
            app.dtP=[];


            % read raw data and parameters
            dt=app.edtShutterTime.Value;
            res=app.edtResolution.Value*1e-6;
            T=app.edtTemperature.Value;
            a=app.edtParticleRadius.Value*1e-6;


            tt=app.tblCurrentTracks.Data;
            app.lblStatus.Interpreter='none';
            if isempty(tt)
                uialert(app.UIFigure,'No tracks added.','Blank data','Icon','error','Modal',true)
                % errordlg('No tracks added.','Blank data','modal')
                return
            end

            drawnow


            % tracks_treatment;
            if app.blnTrackExcluded
                N=length(app.tracks); % N is the number of track files
                for l=1:N
                    tr_temp=app.tracks{l};
                    excluded_rows=false(length(tr_temp),1);
                    for j=1:length(tr_temp)
                        t=tr_temp{j};
                        if ismember(t(1,4),app.excludedTrackID)
                            excluded_rows(j)=true;
                        end
                    end
                    tr_temp(excluded_rows)=[];
                    app.tracks{l}=tr_temp;


                end
                tr_list=app.tracks;


            else
                [N,~]=size(tt); % N is the number of track files
                tr_list=cell(N,1); % pre-allocate
                lastNTrack=0;
                for l=1:N
                    tr=load([tt{l,1} tt{l,2}],'tr');
                    tr=tr.tr;

                    % regard multiple track file as one with extra tracks.
                    % effectively adding to the tr_id's.
                    tr(:,4)=tr(:,4)+lastNTrack;
                    tr=sortrows(tr,[4,3]);

                    % separate by tracks into cell array |tr_list|
                    ind=[[1;find(diff(tr(:,4))~=0)+1],[find(diff(tr(:,4))~=0);length(tr(:,4))]];
                    tr_temp=cell(length(ind(:,1)),1);
                    for i=1:length(ind(:,1))
                        tr_temp{i}=tr(ind(i,1):ind(i,2),:);
                    end % i

                    if length(tr_temp)>1
                        tr_temp=drift_canceling(app,tr_temp);
                    end

                    tr_list{l}=tr_temp;
                    lastNTrack=lastNTrack+length(tr_temp);
                end
            end

            N_tracks_per_tr=arrayfun(@(i) length(tr_list{i}),(1:length(tr_list))','UniformOutput',1);

            dxy=cell(sum(N_tracks_per_tr),1);
            N_tracks_proc=0;
            for l=1:N % loop through number of track files
                tr_temp=tr_list{l};
                % tr_temp have been separated into different tr_id's


                % %% drift estimation
                % % Here, 'drift' refers to the common motion among all
                % % particles. Viewing all particles as a mechanical system,
                % % the rigid-body motion of the particle related to the
                % % field of observation is canceled. However, 'drift' may
                % % refer to the characteristics of the random walk of each
                % % particle. This type of drift is untreated in the present
                % % routine.
                %
                %
                % % calculate the displacements between adjacent frames, and
                % % label them by fr_lags and tr_id's
                % drift_temp=cellfun(@(x) [diff(x(:,1:3),1,1),x(1:end-1,3:4)],tr_temp,'UniformOutput',0);
                %
                % % now each cell of drift_temp: [dx,dy,fr_lags,starting
                % % fr_id, tr_id].
                %
                % drift_temp=cellfun(@(x) x(x(:,3)==1,[1,2,4,5]),drift_temp,'UniformOutput',0);
                % drift_temp=sortrows(cell2mat(drift_temp),[3,4]);
                %
                % % now each cell of drift_temp contains displacement of a
                % % particle between two adjacent frames starting at each
                % % frame. [dx, dy, starting fr_id, tr_id]
                %
                %
                % % Next, separate drift_temp into cell array by starting
                % % fr_id, and average the displacements of all particles in each
                % % frame.
                % ind=[[1;find(diff(drift_temp(:,3))~=0)+1],[find(diff(drift_temp(:,3))~=0);length(drift_temp(:,3))]];
                % drift_temp=cell2mat(arrayfun(@(i,j) [mean(drift_temp(i:j,1:2),1),drift_temp(i,3)],ind(:,1),ind(:,2),'UniformOutput',0));
                % % now drift_temp [mean_dx, mean_dy, starting fr_id]. For no
                % % -drift condition mean_dx=mean_dy=0. So non-zero values of
                % % them quantify the extent of drifting. Such values are
                % % different for different starting fr_id's, which is
                % % regarded as an random walk of pure drift
                %
                %
                % drift_temp(:,1:2)=cumsum(drift_temp(:,1:2),1);
                % % cumsum yield the cumulated drift displacement till each
                % % frame. Particle positions in each frame should substract
                % % the cumulated drift displacement till the same frame for
                % % drift cancelling.

                dxy_temp=cell(length(tr_temp),1);

                for j=1:length(tr_temp)
                    % loop through number of tracks under the given track
                    % file
                    t=tr_temp{j};

                    % % drift canceling
                    % if length(tr_temp)>1
                    %     % drift canceling is not needed for single particle case
                    %     [~,ia,ib]=intersect(t(:,3)-1,drift_temp(:,3));
                    %     t(ia,:)=[t(ia,1:2)-drift_temp(ib,1:2),t(ia,3:4)];
                    % end

                    % lag-time-dependent analysis
                    [p,q]=meshgrid(1:length(t(:,1)),1:length(t(:,1)));
                    pairs=[p(:),q(:)];
                    pairs(pairs(:,1)>=pairs(:,2),:)=[];

                    if ~isempty(pairs)
                        % One cannot calculate 'displacement' out of one
                        % position. Tracks with only one point produce
                        % empty |pairs|, and hence are omitted.

                        t=sortrows([t(pairs(:,2),1)-t(pairs(:,1),1),t(pairs(:,2),2)-t(pairs(:,1),2),...
                            t(pairs(:,2),3)-t(pairs(:,1),3),ones(length(pairs(:,1)),1).*t(1,4)],[3,4]);
                        % now |t| contains [dx,dy,fr_lag, tr_id]

                        % separate |t| by fr_lag into cell array |dxy_temp|
                        ind=[[1;find(diff(t(:,3))~=0)+1],[find(diff(t(:,3))~=0);length(t(:,3))]];
                        temp_cell=cell(length(ind(:,1)),1);
                        for k=1:length(ind(:,1))
                            temp_cell{k}=t(ind(k,1):ind(k,2),:);
                        end % k
                        dxy_temp{j}=temp_cell;


                        N_tracks_proc=N_tracks_proc+1;
                        app.lblStatus.Text=['tr#' num2str(N_tracks_proc)];
                        drawnow
                    end
                end %j
                dxy(sum(N_tracks_per_tr(1:l-1))+1:sum(N_tracks_per_tr(1:l)))=dxy_temp;

                tr_list{l}=tr_temp;
            end %l
            app.tracks=tr_list;

            % clean up the empty cells in dxy due to pre-allocation for
            % un-processed tracks.
            ind=cellfun(@(x) ~isempty(x),dxy,'UniformOutput',1);
            dxy=dxy(ind);

            clearvars dxy_temp tr_list tr_temp temp_cell t tr p q pairs drift_temp;

            app.lblStatus.Text='msd_i';
            drawnow
            % single-particle msd
            raw_msd_i=cell(length(dxy),1);
            for i=1:length(dxy) % loop through total number of tracks
                temp=zeros(length(dxy{i}),3); % preallocate
                for j=1:length(dxy{i})
                    % calculate dr^2=dx^2+dy^2
                    temp(j,:)=[dxy{i}{j}(1,3),mean(sum(dxy{i}{j}(:,1:2).^2,2)),dxy{i}{j}(1,4)]; % msd_i of dx
                end
                raw_msd_i{i}=temp;
            end % i
            % now each cell of |raw_msd_i| contains [fr_lag,msd_i,tr_id] of a
            % track. unit of msd: px^2

            % unit conversion
            app.msd_i=cellfun(@(x) [x(:,1).*dt,x(:,2).*res.^2,x(:,3)],raw_msd_i,'UniformOutput',0);
            clearvars raw_msd_i;

            app.lblStatus.Text='alpha_2_i';
            drawnow
            % single-particle alpha_2
            raw_alpha_2_i=cell(length(dxy),1);
            for i=1:length(dxy)
                temp=zeros(length(dxy{i}),4);
                for j=1:length(dxy{i})
                    Nk=length(dxy{i}{j}(:,1));
                    temp(j,:)=[dxy{i}{j}(1,3),kurtosis(dxy{i}{j}(:,1),0,1)-3,...
                        24.*Nk.*(Nk-1).^2./((Nk+3).*(Nk+5).*(Nk-2).*(Nk-3)),...
                        dxy{i}{j}(1,4)]; % alpha_2_i from dx only
                end
                raw_alpha_2_i{i}=temp(~isnan(temp(:,2)),:);
            end

            % separate by fr_lag into cell array ...
            raw_alpha_2_i=sortrows(cell2mat(raw_alpha_2_i),[1,4]);
            ind=[[1;find(diff(raw_alpha_2_i(:,1))~=0)+1],[find(diff(raw_alpha_2_i(:,1))~=0);length(raw_alpha_2_i(:,1))]];

            % ... and average all single-particle alpha_2 and record their std_err
            app.alpha_2_i=cell2mat(arrayfun(@(i,j) [raw_alpha_2_i(i,1).*dt mean(raw_alpha_2_i(i:j,2)),std(raw_alpha_2_i(i:j,2))],...
                ind(:,1),ind(:,2),'UniformOutput',0));
            clearvars raw_alpha_2_i;


            app.lblStatus.Text='msd';
            drawnow

            % combine displacement of different particles
            dxy=sortrows(cell2mat(cellfun(@(x) cell2mat(cellfun(@(y) y ,x,'UniformOutput',0)),dxy,'UniformOutput',0)),[3,4]);

            % separate by fr_lag into cell array ...
            ind=[[1;find(diff(dxy(:,3))~=0)+1],[find(diff(dxy(:,3))~=0);length(dxy(:,3))]];

            % and calculate dx^2, dy^2 and dr^2
            raw_msd=cell2mat(arrayfun(@(i,j) [dxy(i,3),mean(dxy(i:j,1).^2),...
                mean(dxy(i:j,2).^2), mean(sum(dxy(i:j,1:2).^2,2))],ind(:,1),ind(:,2),'UniformOutput',0));
            app.msd=[raw_msd(:,1).*dt,raw_msd(:,2:4).*res.^2]; % unit conversion
            clearvars raw_msd;


            app.lblStatus.Text='alpha_2';
            drawnow

            % calculate alpha_2 only for dx
            raw_alpha_2=cell2mat(arrayfun(@(i,j) [dxy(i,3).*dt,kurtosis(dxy(i:j,1),0,1)-3],ind(:,1),ind(:,2),'UniformOutput',0));
            Nk=ind(:,2)-ind(:,1)+1;
            app.alpha_2=[raw_alpha_2,abs(sqrt(24.*Nk.*(Nk-1).^2./((Nk+3).*(Nk+5).*(Nk-2).*(Nk-3))))];
            clearvars raw_alpha_2;

            app.lblStatus.Text='van_Hove';
            drawnow
            % histogram dx only
            [N,edges]=arrayfun(@(i,j) histcounts(dxy(i:j,1).*res,100,'Normalization','pdf'),ind(:,1),ind(:,2),'UniformOutput',0);
            app.van_Hove=[N,edges];


            app.lblStatus.Text='Done!';
            drawnow
            clearvars dxy;

            % calculate G' and G''
            app.btnGSEROpt.Enable='off';
            T=app.edtTemperature.Value;
            a=app.edtParticleRadius.Value*1e-6;

            app.GSER_dialogBox=GSER_dlg(app,app.msd,T,a);


            % update slider
            app.sldLagTime.Limits=log10([min(app.msd(:,1)),max(app.msd(:,1))]);
            decade_points=ceil(log10(min(app.msd(:,1)))):floor(log10(max(app.msd(:,1))));
            app.sldLagTime.MajorTicks=decade_points;
            app.sldLagTime.MajorTickLabels=arrayfun(@(i) num2str(10^decade_points(i)),1:length(decade_points),'UniformOutput',0);
            app.sldLagTime.MinorTicks=floor(log10(min(app.msd(:,1)))/0.2)*0.2:0.2:ceil(log10(max(app.msd(:,1)))/0.2)*0.2;
            app.sldLagTime.Value=log10(min(app.msd(:,1)));
            drawnow

            % clear track exclusion flag
            app.blnTrackExcluded=false;

            % update bpgPlotData
            app.optMPMSD.Value=true;
            drawnow


            % plot MP-MSD by default
            reset(app.axePlot)
            app.axePlot.Box='on';
            loglog(app.axePlot,app.msd(:,1),app.msd(:,2:4),'LineWidth',1);

            app.axePlot.XLabel.String='lag time (s)';
            app.axePlot.YLabel.String='MSD (m^2\rm)';
            app.axePlot.XLim=10.^[floor(log10(min(app.msd(:,1)))),ceil(log10(max(app.msd(:,1))))];
            app.axePlot.YLim=10.^[floor(log10(min(app.msd(:,2:4),[],'all'))),ceil(log10(max(app.msd(:,2:4),[],'all')))];
            app.axePlot.XTick=10.^(floor(log10(min(app.msd(:,1)))):ceil(log10(max(app.msd(:,1)))));
            app.axePlot.YTick=10.^(floor(log10(min(app.msd(:,2)))):ceil(log10(max(app.msd(:,2)))));
            app.axePlot.XMinorTick='on';
            app.axePlot.YMinorTick='on';


            hold(app.axePlot)
            x1=10.^(log10(max(app.msd(:,1))/min(app.msd(:,1)))*3/4)*min(app.msd(:,1));
            x2=max(app.msd(:,1));
            y1=interp1(app.msd(:,1),app.msd(:,4),x1);
            y2=y1*x2/x1;
            plot(app.axePlot,[x1,x2],[y1,y2],'k--','LineWidth',1);
            lg=legend(app.axePlot);
            lg.Box='off';
            lg.String=[{'\Delta\itx\rm^2'},{'\Delta\ity\rm^2'},{'\Delta\itr\rm^2'},{},{}];
            lg.Location='best';
            lg.Color='none';
            hold(app.axePlot)
            app.optMPMSD.Value=true;


            drawnow


        end

        % Selection changed function: bgpPlotData
        function bgpPlotDataSelectionChanged(app, event)
            selectedButton = app.bgpPlotData.SelectedObject;
            switch selectedButton
                case app.optMPMSD
                    if ~isempty(app.msd)
                        cla(app.axePlot,'reset')

                        legend(app.axePlot,'off');
                        app.axePlot.Box='on';

                        loglog(app.axePlot,app.msd(:,1),app.msd(:,2:4),'LineWidth',1);

                        app.axePlot.XLabel.String='lag time (s)';
                        app.axePlot.YLabel.String='MSD (m^2\rm)';
                        app.axePlot.XLim=10.^[floor(log10(min(app.msd(:,1)))),ceil(log10(max(app.msd(:,1))))];
                        app.axePlot.YLim=10.^[floor(log10(min(app.msd(:,2:4),[],'all'))),ceil(log10(max(app.msd(:,2),[],'all')))];
                        app.axePlot.XTick=10.^(floor(log10(min(app.msd(:,1)))):ceil(log10(max(app.msd(:,1)))));
                        app.axePlot.YTick=10.^(floor(log10(min(app.msd(:,2)))):ceil(log10(max(app.msd(:,2)))));
                        app.axePlot.XMinorTick='on';
                        app.axePlot.YMinorTick='on';


                        hold(app.axePlot)
                        x1=10.^(log10(max(app.msd(:,1))/min(app.msd(:,1)))*3/4)*min(app.msd(:,1));
                        x2=max(app.msd(:,1));
                        y1=interp1(app.msd(:,1),app.msd(:,4),x1);
                        y2=y1*x2/x1;
                        plot(app.axePlot,[x1,x2],[y1,y2],'k--','LineWidth',1);
                        lg=legend(app.axePlot);
                        lg.Box='off';
                        lg.Color='none';
                        lg.String=[{'\Delta\itx\rm^2'},{'\Delta\ity\rm^2'},{'\Delta\itr\rm^2'},{},{}];
                        lg.Location='best';
                        hold(app.axePlot)

                        app.optMPMSD.Value=true;
                    end
                case app.optGSER
                    if ~isempty(app.msd)
                        cla(app.axePlot,'reset');

                        app.axePlot.Box='on';
                        if ~isempty(app.G)
                            loglog(app.axePlot,app.G(:,1),app.G(:,2:3),'LineWidth',1);


                            app.axePlot.XLabel.String='angular frequency (rad/s)';
                            app.axePlot.YLabel.String='Moduli (Pa)';
                            app.axePlot.XLim=10.^[floor(log10(min(app.G(:,1)))),ceil(log10(max(app.G(:,1))))];
                            app.axePlot.YLim=10.^[floor(log10(min([app.G(app.G(:,2)>0,2);app.G(app.G(:,3)>0,3)]))),...
                                ceil(log10(max([app.G(app.G(:,2)>0,2);app.G(app.G(:,3)>0,3)])))];
                            app.axePlot.XTick=10.^(floor(log10(min(app.G(:,1)))):ceil(log10(max(app.G(:,1)))));
                            app.axePlot.YTick=10.^(floor(log10(min([app.G(app.G(:,2)>0,2);app.G(app.G(:,3)>0,3)]))):...
                                ceil(log10(max([app.G(app.G(:,2)>0,2);app.G(app.G(:,3)>0,3)]))));
                            app.axePlot.XMinorTick='on';
                            app.axePlot.YMinorTick='on';

                            lg=legend(app.axePlot);

                            lg.Interpreter='latex';
                            lg.String=[{'$G\prime$'},{'$G\prime\prime$'}];
                            lg.Location='best';
                            lg.Box='off';
                            lg.Color='none';
                        end
                    end
                case app.optAlpha_2
                    if ~isempty(app.msd)
                        cla(app.axePlot,'reset')

                        app.axePlot.Box='on';


                        line_color=app.axePlot.ColorOrder(1,:);
                        bar_color=rgb2hsv(line_color);

                        bar_color(3)=bar_color(3)+(1-bar_color(3))*3/4;
                        bar_color=hsv2rgb(bar_color);


                        errorbar(app.axePlot,app.alpha_2_i(:,1),app.alpha_2_i(:,2),app.alpha_2_i(:,3),...
                            'LineStyle','none','CapSize',0,'LineWidth',1,'Color',bar_color,'HandleVisibility','off');
                        hold(app.axePlot,'on')
                        plot(app.axePlot,app.alpha_2_i(:,1),app.alpha_2_i(:,2),'LineWidth',1,'Color',line_color);

                        line_color=app.axePlot.ColorOrder(2,:);
                        bar_color=rgb2hsv(line_color);

                        bar_color(3)=bar_color(3)+(1-bar_color(3))*3/4;
                        bar_color=hsv2rgb(bar_color);

                        errorbar(app.axePlot,app.alpha_2(:,1),app.alpha_2(:,2),app.alpha_2(:,3),...
                            'LineStyle','none','CapSize',0,'LineWidth',1,'Color',bar_color,'HandleVisibility','off');
                        plot(app.axePlot,app.alpha_2(:,1),app.alpha_2(:,2),'LineWidth',1,'Color',line_color);

                        hold(app.axePlot,'off')
                        app.axePlot.XScale='log';
                        app.axePlot.YScale='linear';


                        app.axePlot.XLabel.String='lag time (s)';
                        app.axePlot.YLabel.Interpreter='latex';
                        app.axePlot.YLabel.String='$\alpha_2$ (-)';
                        app.axePlot.XLim=10.^[floor(log10(min(app.msd(:,1)))),ceil(log10(max(app.msd(:,1))))];
                        %                     app.axePlot.YLim=[floor(min(0,min(app.alpha_2(:,2)))),ceil(max(app.alpha_2(:,2)))];
                        app.axePlot.XTick=10.^(floor(log10(min(app.msd(:,1)))):ceil(log10(max(app.msd(:,1)))));
                        %                     app.axePlot.YTick=10.^(floor(log10(min(app.msd(:,2)))):ceil(log10(max(app.msd(:,2)))));
                        app.axePlot.XMinorTick='on';
                        app.axePlot.YMinorTick='on';


                        lg=legend(app.axePlot);
                        lg.String=[{'single'},{'multiple'}];
                        lg.Location='best';
                        lg.Color='none';
                        lg.Box='off';
                    end
                case app.optVanHove

                    if ~isempty(app.msd)
                        dt=10.^app.sldLagTime.Value;
                        [~,ind]=min(abs(app.msd(:,1)-dt));

                        cla(app.axePlot,'reset')
                        app.axePlot.Box='on';

                        legend(app.axePlot,'off');
                        N=app.van_Hove{ind,1};
                        edges=app.van_Hove{ind,2};
                        bins=edges(1:end-1)+diff(edges)./2;

                        plot(app.axePlot,bins,N,'.','LineWidth',1);
                        app.axePlot.XScale='linear';
                        app.axePlot.YScale='log';
                        hold(app.axePlot,'on')
                        plot(app.axePlot,bins,exp(-bins.^2./(2.*app.msd(ind,2)))./sqrt(2.*pi.*app.msd(ind,2)),'-','LineWidth',1,'Color',[0.8500 0.3250 0.0980])
                        hold(app.axePlot,'off')


                        if ~isempty(app.van_Hove_rec)
                            [~,ind2]=min(abs(app.msd(:,1)-app.dtP));
                            if ind2==ind
                                hold(app.axePlot,'on');
                                plot(app.axePlot,app.van_Hove_rec(:,1),app.van_Hove_rec(:,2),'-','LineWidth',1,'Color',[0.9290 0.6940 0.1250])
                                hold(app.axePlot,'off');
                            end
                        end

                        app.axePlot.XLim=[-10.^max(log10(abs(bins))),10.^max(log10(abs(bins)))];
                        app.axePlot.YLim=10.^[floor(log10(min(N(N>0)))),ceil(log10(max(N)))];
                        app.axePlot.YTick=10.^(floor(log10(min(N(N>0)))):ceil(log10(max(N))));
                        app.axePlot.XMinorTick='on';
                        app.axePlot.YMinorTick='on';


                        app.axePlot.XLabel.Interpreter='tex';
                        app.axePlot.XLabel.String='Δ\itx\rm (m)';
                        app.axePlot.YLabel.Interpreter='tex';
                        app.axePlot.YLabel.String='prob. density (-)';

                        tx=text(app.axePlot,app.axePlot.XLim(1),app.axePlot.YLim(2),[' Δ\itt\rm = ' num2str(app.msd(ind,1),'%.3g') ' s']);
                        tx.VerticalAlignment='top';
                        tx.Interpreter='tex';
                    end
                case app.optSPMSD
                    if ~isempty(app.msd)
                        cla(app.axePlot,'reset');
                        app.axePlot.Box='on';

                        legend(app.axePlot,'off');

                        co=app.axePlot.ColorOrder;
                        for i=1:length(co(:,1))
                            co_2=rgb2hsv(co(i,:));
                            co_2(3)=co_2(3)+(1-co_2(3))*3/4;
                            co(i,:)=hsv2rgb(co_2);
                        end
                        app.axePlot.ColorOrder=co;


                        hold(app.axePlot,'on')
                        cellfun(@(x) plot(app.axePlot,x(:,1),x(:,2)),app.msd_i,'UniformOutput',0);
                        app.axePlot.XScale='log';
                        app.axePlot.YScale='log';

                        loglog(app.axePlot,app.msd(:,1),app.msd(:,2),'k--','LineWidth',1)
                        hold(app.axePlot,'off');

                        app.axePlot.XLabel.String='lag time (s)';
                        app.axePlot.YLabel.String='MSD (m^2\rm)';
                        app.axePlot.XLim=10.^[floor(log10(min(app.msd(:,1)))),ceil(log10(max(app.msd(:,1))))];
                        app.axePlot.YLim=10.^[floor(log10(min(app.msd(:,2:4),[],'all'))),ceil(log10(max(app.msd(:,2),[],'all')))];
                        app.axePlot.XTick=10.^(floor(log10(min(app.msd(:,1)))):ceil(log10(max(app.msd(:,1)))));
                        app.axePlot.YTick=10.^(floor(log10(min(app.msd(:,2)))):ceil(log10(max(app.msd(:,2)))));
                        app.axePlot.XMinorTick='on';
                        app.axePlot.YMinorTick='on';
                    end

                case app.optChi4
                    if ~isempty(app.chi4_s)

                        cla(app.axePlot,'reset');
                        app.axePlot.Box='on';

                        legend(app.axePlot,'off');

                        semilogx(app.axePlot,app.chi4_s(:,1),app.chi4_s(:,2),'-','LineWidth',1);


                        app.axePlot.XLabel.String='lag time (s)';
                        app.axePlot.YLabel.Interpreter='tex';
                        app.axePlot.YLabel.String='\it\chi\rm_4 (m^3\rm)';
                        app.axePlot.XLimMode='auto';
                        app.axePlot.YLimMode='auto';
                        % app.axePlot.XLim=10.^[floor(log10(min(app.chi4_s(:,1)))),ceil(log10(max(app.chi4_s(:,1))))];
                        % app.axePlot.YLim=10.^[floor(log10(min(app.chi4_s(:,2)))),ceil(log10(max(app.chi4_s(:,2))))];
                        % app.axePlot.XTick=10.^(floor(log10(min(app.chi4_s(:,1)))):ceil(log10(max(app.chi4_s(:,1)))));
                        % app.axePlot.YTick=10.^(floor(log10(min(app.chi4_s(:,2)))):ceil(log10(max(app.chi4_s(:,2)))));
                        app.axePlot.XMinorTick='on';
                        app.axePlot.YMinorTick='on';
                    end


            end
        end

        % Value changed function: sldLagTime
        function sldLagTimeValueChanged(app, event)
            value = app.sldLagTime.Value;
            if ~isempty(app.msd)
                slider_value=10.^value;
                [~,ind]=min(abs(app.msd(:,1)-slider_value));
                app.sldLagTime.Value=log10(app.msd(ind,1));
                drawnow

                if app.optVanHove.Value
                    dt=10.^value;
                    [~,ind]=min(abs(app.msd(:,1)-dt));

                    reset(app.axePlot)
                    app.axePlot.Box='on';
                    app.axePlot.XMinorTick='on';
                    app.axePlot.YMinorTick='on';

                    legend(app.axePlot,'off');
                    N=app.van_Hove{ind,1};
                    edges=app.van_Hove{ind,2};
                    bins=edges(1:end-1)+diff(edges)./2;

                    plot(app.axePlot,bins,N,'.','LineWidth',1);
                    app.axePlot.XScale='linear';
                    app.axePlot.YScale='log';
                    hold(app.axePlot)
                    plot(app.axePlot,bins,exp(-bins.^2./(2.*app.msd(ind,2)))./sqrt(2.*pi.*app.msd(ind,2)),'-','LineWidth',1,'Color',[0.8500 0.3250 0.0980])
                    hold(app.axePlot)


                    if ~isempty(app.van_Hove_rec)
                        [~,ind2]=min(abs(app.msd(:,1)-app.dtP));
                        if ind2==ind
                            hold(app.axePlot,'on');
                            plot(app.axePlot,app.van_Hove_rec(:,1),app.van_Hove_rec(:,2),'-','LineWidth',1,'Color',[0.9290 0.6940 0.1250])
                            hold(app.axePlot,'off');
                        end
                    end


                    app.axePlot.XLim=[-10.^max(log10(abs(bins))),10.^max(log10(abs(bins)))];
                    app.axePlot.YLim=10.^[floor(log10(min(N(N>0)))),ceil(log10(max(N)))];
                    app.axePlot.YTick=10.^(floor(log10(min(N(N>0)))):ceil(log10(max(N))));
                    app.axePlot.Title.String='van Hove';
                    app.axePlot.XLabel.Interpreter='tex';
                    app.axePlot.XLabel.String='\Delta\itx\rm (m)';
                    app.axePlot.YLabel.Interpreter='tex';
                    app.axePlot.YLabel.String='prob. density (-)';


                    tx=text(app.axePlot,app.axePlot.XLim(1),app.axePlot.YLim(2),[' Δ\itt\rm = ' num2str(app.msd(ind,1),'%.3g') ' s']);
                    tx.VerticalAlignment='top';
                end

            end
        end

        % Button pushed function: btnRemove
        function btnRemoveButtonPushed(app, event)
            t=app.tblCurrentTracks.Data;
            if isempty(app.CurrentSelectedRow)||app.CurrentSelectedRow>length(t(:,1))

                uialert(app.UIFigure,'Selet a row first.','No row selected.','Icon','warning')
                return
            end


            t(app.CurrentSelectedRow,:)=[];


            app.tblCurrentTracks.Data=t;
            app.blnTrackExcluded=false;
        end

        % Button pushed function: btnLucy
        function btnLucyButtonPushed(app, event)
            if isempty(app.msd)
                uialert(app.UIFigure,'Calculate first.','Blank data','Icon','info')
                return;
            end
            dt=10.^app.sldLagTime.Value;


            app.btnLucy.Enable='off';
            app.Lucy_dialogBox=Lucy_dlg(app,app.msd,app.van_Hove,dt);
            %
            %
            %             [~,l]=min(abs(app.msd(:,1)-dt));
            %             x=(app.van_Hove{l,2}(1:end-1)+mean(diff(app.van_Hove{l,2}))./2)';
            %
            %             dx=diff(app.van_Hove{l,2});
            %             dx=dx(:);
            %             phi_tilde=app.van_Hove{l,1}'.*dx;
            %
            %             D_est=app.msd(l,2)./(2.*dt);
            %
            %             % modify the range of D here
            %             xi_bin=logspace(round(log10(D_est)-3),round(log10(D_est)+3),1001);
            %
            %             xi_bin=xi_bin(:);
            %             dxi=diff(xi_bin);
            %             dxi=dxi(:);
            %             xi=xi_bin(1:end-1)+dxi./2;
            %             xi=xi(:);
            %
            %             psi=(xi./D_est.^2).*exp(-xi.^2./(2.*D_est.^2)).*dxi;
            %             psi=psi./sum(psi)
            %
            %             % phi0=zeros(length(x),5000);
            %             % Q=zeros(length(x),5000);
            %             % psi0=phi0;
            %             % P=zeros(length(x),length(xi));
            %
            %             % for j=1:length(xi)
            %             %     P(:,j)=(1./sqrt(4.*pi.*xi(j).*dt)).*exp(-x.^2./(4.*xi(j).*dt)).*dx;
            %             % end
            % % construct the kernel matrix P
            % [XI, X] = meshgrid(xi, x);
            % P = (1 ./ sqrt(4 .* pi .* XI .* dt)) .* exp(-X.^2 ./ (4 .* XI .* dt)) .* dx;
            %
            %
            %             change=100;
            %
            %
            %             while change>0.01 % modify the escaping condition here
            %                 for j=1:length(xi)
            %
            %                     phi0(:,j)=psi(j).*P(:,j);
            %
            %                 end
            %                 phi=sum(phi0,2);
            %                 for j=1:length(xi)
            %                     Q(:,j)=psi(j).*P(:,j)./phi;
            %                 end
            %                 for k=1:length(x)
            %                     psi0(k,:)=phi_tilde(k).*Q(k,:);
            %                 end
            %                 psi_old=psi;
            %                 psi=sum(psi0,1);
            %                 psi=psi(:);
            %                 psi(psi<10e-324)=5e-324;
            %                 psi_old=psi_old(:);
            %                 temp=abs(psi_old-psi)./psi;
            %                 temp=temp(temp~=inf);
            %                 change=mean(temp,'omitnan');
            %
            %                 app.lblStatus.Text=sprintf('%.3e %',...
            %                     change-0.01);
            %                 drawnow;
            %
            %             end % set break point here
            %
            %
            %             app.p_D=[xi,psi./dxi]; % distribution density (bin-width dependent)
            %             app.P_D=[xi,psi]; % distribution
            %             P_com=phi./dx;
            %             app.dtP=dt;
            %             app.van_Hove_rec=[app.van_Hove{l,2}(1:end-1)',P_com(:,1)]; % reconstructed van_Hove
            %
            %
            %             cla(app.axePlot,'reset');
            %             app.axePlot.Box='on';
            %             app.axePlot.XMinorTick='on';
            %             app.axePlot.YMinorTick='on';
            %
            %             legend(app.axePlot,'off');
            %             yyaxis(app.axePlot,'left');
            %             area(app.axePlot,log10(app.P_D(:,1)),app.P_D(:,2),'LineStyle','none');
            %             ylabel(app.axePlot,'\itP\rm(\itD\rm)')
            %             yyaxis(app.axePlot,'right');
            %             plot(app.axePlot,log10(app.P_D(:,1)),cumsum(app.P_D(:,2)),'-','LineWidth',1);
            %             app.axePlot.YTickLabel=[];
            %             app.axePlot.XLabel.String='log(\itD\rm (m^2/s))';
            %             tx=text(app.axePlot,app.axePlot.XLim(1),app.axePlot.YLim(2),[' \Delta\it t\rm = ' sprintf('%.2e',app.msd(l,1)) ' s']);
            %             tx.Interpreter='tex';
            %             tx.VerticalAlignment='top';
            %
            %             app.lblStatus.Text='Done!';
            %             drawnow


        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            if isempty(app.msd)&isempty(app.chi4_s)
                uialert(app.UIFigure,'No results saved.','Canceled','Icon','info');
                figure(app.UIFigure);
                return
            else
                path=uigetdir(app.CurrentPath,'Select folder for saving data');
                if path~=0
                    app.CurrentPath=[path '\'];

                    if ~isempty(app.msd)
                        msd_i=app.msd_i;
                        msd=app.msd;
                        G=app.G;
                        alpha_2=app.alpha_2;
                        alpha_2_i=app.alpha_2_i;
                        van_Hove=app.van_Hove;

                        save([path '\msd_data.mat'], ...
                            'msd_i','msd','G','alpha_2',...
                            'alpha_2','alpha_2_i','van_Hove');
                    end

                    if~isempty(app.P_D)
                        p_D=app.p_D;
                        P_D=app.P_D;
                        van_Hove_rec=app.van_Hove_rec;
                        save([path '\PD_data_' sprintf('%.2e',app.dtP),'.mat'],...
                            'p_D','P_D','van_Hove_rec');
                    end

                    if ~isempty(app.chi4_s)
                        chi4_s=app.chi4_s;
                        chi4_d=app.chi4_d;
                        chi4_t=app.chi4_t;
                        Q_s=app.Q_s;
                        Q_d=app.Q_d;
                        Q_t=app.Q_t;
                        save(fullfile(path,'chi4.mat'),'chi4_s','chi4_d','chi4_t','Q_s','Q_d','Q_t');
                    end
                else
                    uialert(app.UIFigure,'No results saved.','Canceled','Icon','info');
                    figure(app.UIFigure);
                    return
                end


                % if isempty(app.msd)
                %     uialert(app.UIFigure,'No MSD data to save.','Blank data','Icon','info');
                %     return
                % end
                % path=uigetdir(app.CurrentPath,'Select folder for saving data');
                % if path~=0
                %
                %
                %     msd_i=app.msd_i;
                %     msd=app.msd;
                %     G=app.G;
                %     alpha_2=app.alpha_2;
                %     alpha_2_i=app.alpha_2_i;
                %     van_Hove=app.van_Hove;
                %
                %     save([path '\msd_data.mat'], ...
                %         'msd_i','msd','G','alpha_2',...
                %         'alpha_2','alpha_2_i','van_Hove');
                %
                %     app.CurrentPath=[path '\'];
                %     if isempty(app.P_D)
                %         uialert(app.UIFigure,'No P(D) data to save.','Blank data','Icon','info');
                %         return
                %     end
                %
                %     p_D=app.p_D;
                %     P_D=app.P_D;
                %     van_Hove_rec=app.van_Hove_rec;
                %     save([path '\PD_data_' sprintf('%.2e',app.dtP),'.mat'],...
                %         'p_D','P_D','van_Hove_rec');
                %     app.CurrentPath=[path '\'];
                % else
                %     uialert(app.UIFigure,'No results saved.','Canceled','Icon','info');
                %     figure(app.UIFigure);
                %     return
                % end
            end
        end

        % Button pushed function: btnGSEROpt
        function btnGSEROptButtonPushed(app, event)
            if isempty(app.msd)
                uialert(app.UIFigure,'Calculate first.','Blank data','Icon','info')
                return;
            end

            app.btnGSEROpt.Enable='off';
            T=app.edtTemperature.Value;
            a=app.edtParticleRadius.Value*1e-6;

            app.GSER_dialogBox=GSER_dlg(app,app.msd,T,a);

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)

            delete(app.GSER_dialogBox)
            delete(app.traj_dialogBox)
            delete(app.Lucy_dialogBox)

            delete(app)
        end

        % Button pushed function: btnChi_4
        function btnChi_4ButtonPushed(app, event)
            % uialert(app.UIFigure,'All tracks should belong to one FOV','Notice','Icon','info','Modal',true);
            app.lblStatus.Interpreter='tex';
            app.lblStatus.Text='\it\chi\rm_4...';


            a=app.edtParticleRadius.Value.*1e-6;
            res=app.edtResolution.Value.*1e-6;

            d=a./res; % overlap tolerance in pixel;
            tt=app.tblCurrentTracks.Data;
            if isempty(tt)
                uialert(app.UIFigure,'No tracks added.','Blank data','Modal',true,'Icon','error')
                return
            end

            drawnow
            [N_tr,~]=size(tt); % N is the number of track files


            Q_s=cell(N_tr,1);
            Q_d=Q_s;
            Q_t=Q_s;
            for k=1:N_tr
                %% load the k-th track data


                tr=load([tt{k,1} tt{k,2}],'tr');
                tr=tr.tr;
                % cut data for fast calculation; delete this line for full calculation.
                %     tr=tr(tr(:,3)<=200,:); % cutoff by fr_id

                %% How many different values of frame lags can the data afford?
                N_fr=max(tr(:,3))-min(tr(:,3))+1;
                % N_fr_lags=N_fr; % equals to the number of frames since fr_lag = 0 also counts

                %% drift canceling
                tr=sortrows(tr,[4,3]); % sort by tr_id first

                % group positions of each particle into a cell array by its tr_id
                ind=[[1;find(diff(tr(:,4))~=0)+1],[find(diff(tr(:,4))~=0);length(tr(:,4))]];
                tr_temp=arrayfun(@(i) tr(ind(i,1):ind(i,2),:),(1:length(ind(:,1)))','UniformOutput',0);

                if length(tr_temp)>1
                    tr_temp=drift_canceling(app,tr_temp);
                end
                %
                % % calculate the displacements between adjacent frames, and label them by
                % % staring_fr_id's and tr_id's. [dx,dy,_starting_fr_id,tr_id]
                % drift=cellfun(@(x) [diff(x(:,1:3),1,1),x(1:end-1,3:4)],tr_temp,'UniformOutput',0);
                % drift=cellfun(@(x) x(x(:,3)==1,[1,2,4,5]),drift,'UniformOutput',0);
                %
                % drift=sortrows(cell2mat(drift),[3,4]);
                %
                % % group drift_temp into cell array by starting_fr_id's, and average the
                % % dispacements of each starting_fr_id. [mean_dx,mean_dy, starting_fr_id]
                % ind=[[1;find(diff(drift(:,3))~=0)+1],[find(diff(drift(:,3))~=0);length(drift(:,3))]];
                % drift=cell2mat(arrayfun(@(i,j) [mean(drift(i:j,1:2),1),drift(i,3)],ind(:,1),ind(:,2),'UniformOutput',0));
                %
                % % For no-drift condition mean_dx=mean_dy=0, so their non-zero values
                % % quantify the drifting displacement between adjacent frames starting from
                % % each starting_fr_id. What we need is the cumulated drifting displacement
                % % till a given fr_id, so we do the following cumsum:
                % drift(:,1:2)=cumsum(drift(:,1:2),1);
                %
                % % now particle position in each frame should substract by the cumulated
                % % drift displacement till the same frame for drift canceling
                % for i=1:length(tr_temp)
                %     t=tr_temp{i};
                %     [~,ia,ib]=intersect(t(:,3)-1,drift(:,3));
                %     t(ia,:)=[t(ia,1:2)-drift(ib,1:2),t(ia,3:4)];
                %     tr_temp{i}=t;
                % end
                % % the drift-cancelled tr_temp is reserved for the next section
                tr=cell2mat(tr_temp);

                %% Common particle ID's among all frames

                divide_frames=1;
                pt_id=[];
                while isempty(pt_id)&N_fr>=10
                    tr=sortrows(tr,[4,3]); % sort by tr_id first
                    N_fr=max(tr(:,3))-min(tr(:,3))+1;

                    % group positions of each particle into a cell array by its tr_id
                    ind=[[1;find(diff(tr(:,4))~=0)+1],[find(diff(tr(:,4))~=0);length(tr(:,4))]];
                    tr_temp=arrayfun(@(i) tr(ind(i,1):ind(i,2),:),(1:length(ind(:,1)))','UniformOutput',0);

                    N_fr=round(N_fr./divide_frames);

                    % boolean tag for each particle id denoting whether it should be excluded
                    pt_excl=false(length(tr_temp),1);
                    for i=1:length(tr_temp)


                        pt_temp=tr_temp{i};
                        pt_temp=pt_temp(pt_temp(:,3)<=N_fr,:);


                        % A particle that is not appearing in every frame is characterized by
                        % incomplete/discontinuous frame column
                        pt_excl(i)=any(diff(sort(unique([0;pt_temp(:,3);N_fr])))~=1);

                    end
                    pt_id=unique(tr(:,4)); % id's of all particles
                    pt_id(pt_excl)=[]; % exclusion
                    tr_temp=tr_temp(~pt_excl); % exclusion
                    arrayfun(@(i) tr_temp{i}(tr_temp{i}(:,3)<=N_fr,:), 1:length(tr_temp),'UniformOutput',false);
                    tr_temp_mat=cell2mat(tr_temp); % recover tr into matrix
                    N_pt=length(pt_id); % Now each frame has the same number of particle N_pt
                    divide_frames=divide_frames+1;
                end
                tr=tr_temp_mat;


                if N_pt~=0

                    %% Generate all distinct pairs of frames
                    pairs=combvec(1:N_fr,1:N_fr);
                    pairs=pairs';
                    pairs(pairs(:,2)<pairs(:,1),:)=[];

                    % Group all pairs of frames by frame lags
                    pairs=[pairs,diff(pairs,1,2)];
                    pairs=sortrows(pairs,3);
                    fr_pairs=pairs;

                    % Now |fr_pairs| contains all distinct pairs of frames in terms of fr_id's


                    %% Calculate all q's
                    Q_s_temp=zeros(length(fr_pairs(:,1)),2); % pre-allocate
                    Q_d_temp=Q_s_temp;
                    Q_t_temp=Q_s_temp;
                    parfor i=1:length(fr_pairs(:,1))
                        % for i=1:length(fr_pairs(:,1))
                        % For each distinct pair of frame, extract corresponding position data
                        % from |tr|
                        t_fr1=tr(tr(:,3)==fr_pairs(i,1),:);
                        t_fr2=tr(tr(:,3)==fr_pairs(i,2),:);

                        % Pair the particles each from one of the two frames and calculate q's
                        pt_pairs=combvec(1:length(t_fr1(:,1)),1:length(t_fr2(:,1)));
                        pt_pairs=pt_pairs';

                        % the calculated distance is dr^2=dx^2+dy^2
                        dr2=sum((t_fr1(pt_pairs(:,1),1:2)-t_fr2(pt_pairs(:,2),1:2)).^2,2);

                        % the calculated distance is dx^2
                        dr=(t_fr1(pt_pairs(:,1),1)-t_fr2(pt_pairs(:,2),1)).^2;

                        q_temp=[exp(-dr2./(2.*d.^2)),t_fr1(pt_pairs(:,1),4),t_fr2(pt_pairs(:,2),4)];
                        q_self=[sum(q_temp(q_temp(:,2)==q_temp(:,3),1))./N_pt,fr_pairs(i,3)];
                        q_dist=[sum(q_temp(q_temp(:,2)~=q_temp(:,3),1))./N_pt,fr_pairs(i,3)];
                        q_tot=[q_self(:,1)+q_dist(:,1),fr_pairs(i,3)];

                        Q_s_temp(i,:)=q_self;
                        Q_d_temp(i,:)=q_dist;
                        Q_t_temp(i,:)=q_tot;
                    end
                    Q_s{k}=Q_s_temp;
                    Q_d{k}=Q_d_temp;
                    Q_t{k}=Q_t_temp;
                end
                app.lblStatus.Text=join(["tr#",num2str(k)]);
                app.lblStatus.Interpreter='none';
                drawnow
            end
            % clearvars -except Q_s Q_d Q_t path

            Q_s=cell2mat(Q_s);
            Q_d=cell2mat(Q_d);
            Q_t=cell2mat(Q_t);

            if ~isempty(N_pt)


                %% Calculate chi4
                app.lblStatus.Text="\chi_4 ...";
                app.lblStatus.Interpreter='tex';
                dt=app.edtShutterTime.Value;
                fr_lags=unique(Q_s(:,2));


                chi4_s=zeros(length(fr_lags),1);
                chi4_d=chi4_s;
                chi4_t=chi4_s;
                parfor i=1:length(fr_lags)
                    chi4_s(i)=var(Q_s(Q_s(:,2)==fr_lags(i),1));
                end
                chi4_s=[fr_lags.*dt,(sqrt(chi4_s).*res).^3];
                parfor i=1:length(fr_lags)
                    chi4_d(i)=var(Q_d(Q_d(:,2)==fr_lags(i),1));
                end
                chi4_d=[fr_lags.*dt,(sqrt(chi4_d).*res).^3];

                parfor i=1:length(fr_lags)
                    chi4_t(i)=var(Q_t(Q_t(:,2)==fr_lags(i),1));
                end
                chi4_t=[fr_lags.*dt,(sqrt(chi4_t).*res).^3];

                %% Calculate mean Q
                app.lblStatus.Text="Q ...";
                app.lblStatus.Interpreter='none';
                Q_s_mean=zeros(length(fr_lags),1);
                Q_d_mean=Q_s_mean;
                Q_t_mean=Q_s_mean;
                parfor i=1:length(fr_lags)
                    Q_s_mean(i)=mean(Q_s(Q_s(:,2)==fr_lags(i),1));
                end
                Q_s_mean=[fr_lags.*dt,Q_s_mean];
                parfor i=1:length(fr_lags)
                    Q_d_mean(i)=mean(Q_d(Q_d(:,2)==fr_lags(i),1));
                end
                Q_d_mean=[fr_lags.*dt,Q_d_mean];
                parfor i=1:length(fr_lags)
                    Q_t_mean(i)=mean(Q_d(Q_d(:,2)==fr_lags(i),1));
                end
                Q_t_mean=[fr_lags.*dt,Q_t_mean];

                %% commit results
                app.chi4_s=chi4_s;
                app.chi4_d=chi4_d;
                app.chi4_t=chi4_t;
                app.Q_s=Q_s_mean;
                app.Q_d=Q_d_mean;
                app.Q_t=Q_t_mean;
                app.lblStatus.Text='Done!';
                app.lblStatus.Interpreter='none';
                % update plot
                if app.optChi4.Value

                    cla(app.axePlot,'reset');
                    app.axePlot.Box='on';

                    legend(app.axePlot,'off');

                    semilogx(app.axePlot,app.chi4_s(:,1),app.chi4_s(:,2),'-','LineWidth',1);


                    app.axePlot.XLabel.String='lag time (s)';
                    app.axePlot.YLabel.Interpreter='tex';
                    app.axePlot.YLabel.String='\it\chi\rm_4 (m^3\rm)';
                    app.axePlot.XLimMode='auto';
                    app.axePlot.YLimMode='auto';
                    % app.axePlot.XLim=10.^[floor(log10(min(app.chi4_s(:,1)))),ceil(log10(max(app.chi4_s(:,1))))];
                    % app.axePlot.YLim=10.^[floor(log10(min(app.chi4_s(:,2)))),ceil(log10(max(app.chi4_s(:,2))))];
                    % app.axePlot.XTick=10.^(floor(log10(min(app.chi4_s(:,1)))):ceil(log10(max(app.chi4_s(:,1)))));
                    % app.axePlot.YTick=10.^(floor(log10(min(app.chi4_s(:,2)))):ceil(log10(max(app.chi4_s(:,2)))));
                    app.axePlot.XMinorTick='on';
                    app.axePlot.YMinorTick='on';

                end


            else
                app.lblStatus.Text='No data.';
            end

        end

        % Button pushed function: btnTrajectories
        function btnTrajectoriesPushed(app, event)
            if isempty(app.msd)
                uialert(app.UIFigure,'Calculate first.','Blank data','Icon','info')
                return;
            end
            % dt=10.^app.sldLagTime.Value;
            %
            %
            app.btnTrajectories.Enable='off';

            % if app.blnTrackExcluded
            %     tt=app.tblCurrentTracks.Data;
            %                     [N,~]=size(tt); % N is the number of track files
            %                 tr_list=cell(N,1); % pre-allocate
            %                 lastNTrack=0;
            %                 for l=1:N
            %                     tr=load([tt{l,1} tt{l,2}],'tr');
            %                     tr=tr.tr;
            %
            %                     % regard multiple track file as one with extra tracks.
            %                     % effectively adding to the tr_id's.
            %                     tr(:,4)=tr(:,4)+lastNTrack;
            %                     tr=sortrows(tr,[4,3]);
            %
            %                     % separate by tracks into cell array |tr_list|
            %                     ind=[[1;find(diff(tr(:,4))~=0)+1],[find(diff(tr(:,4))~=0);length(tr(:,4))]];
            %                     tr_temp=cell(length(ind(:,1)),1);
            %                     for i=1:length(ind(:,1))
            %                         tr_temp{i}=tr(ind(i,1):ind(i,2),:);
            %                     end % i
            %
            %                     if length(tr_temp)>1
            %                         tr_temp=drift_canceling(app,tr_temp);
            %                     end
            %
            %                     tr_list{l}=tr_temp;
            %                     lastNTrack=lastNTrack+length(tr_temp);
            %                 end
            %                 app.tracks=tr_list;
            % end
            %
            % app.Lucy_dialogBox=Lucy_dlg(app,app.msd,app.van_Hove,dt);
            app.traj_dialogBox=traj_dlg(app,app.tracks,app.msd_i,app.msd);

        end

        % Selection changed function: tblCurrentTracks
        function tblCurrentTracksSelectionChanged(app, event)
            selection = app.tblCurrentTracks.Selection;
            app.CurrentSelectedRow=selection;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Track Analysis // Weixiang Sun // mswxsun@scut.edu.cn';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.axePlot = uiaxes(app.UIFigure);
            xlabel(app.axePlot, 'X')
            ylabel(app.axePlot, 'Y')
            app.pnlParameters = uipanel(app.UIFigure);
            app.pnlParameters.Title = 'Parameters';
            app.pnlParameters.Tag = 'pnlParameters';
            app.TemperatureCLabel = uilabel(app.pnlParameters);
            app.TemperatureCLabel.Text = 'Temperature (°C):';
            app.edtTemperature = uieditfield(app.pnlParameters, 'numeric');
            app.edtTemperature.Limits = [0 Inf];
            app.edtTemperature.ValueChangedFcn = createCallbackFcn(app, @edtTemperatureValueChanged, true);
            app.edtTemperature.Value = 30;
            app.ParticleradiusmLabel = uilabel(app.pnlParameters);
            app.ParticleradiusmLabel.Text = 'Particle radius (μm):';
            app.edtParticleRadius = uieditfield(app.pnlParameters, 'numeric');
            app.edtParticleRadius.Limits = [0 Inf];
            app.edtParticleRadius.ValueChangedFcn = createCallbackFcn(app, @edtParticleRadiusValueChanged, true);
            app.edtParticleRadius.Value = 0.1;
            app.ShuttertimesLabel = uilabel(app.pnlParameters);
            app.ShuttertimesLabel.Text = 'Shutter time (s):';
            app.edtShutterTime = uieditfield(app.pnlParameters, 'numeric');
            app.edtShutterTime.Limits = [0 Inf];
            app.edtShutterTime.ValueChangedFcn = createCallbackFcn(app, @edtShutterTimeValueChanged, true);
            app.edtShutterTime.Value = 0.020142;
            app.ResolutionmpixelLabel = uilabel(app.pnlParameters);
            app.ResolutionmpixelLabel.Text = 'Resolution (μm/pixel):';
            app.edtResolution = uieditfield(app.pnlParameters, 'numeric');
            app.edtResolution.Limits = [0 Inf];
            app.edtResolution.ValueChangedFcn = createCallbackFcn(app, @edtResolutionValueChanged, true);
            app.edtResolution.Value = 0.1083266701;
            app.btnAddTrack = uibutton(app.UIFigure, 'push');
            app.btnAddTrack.ButtonPushedFcn = createCallbackFcn(app, @btnAddTrackButtonPushed, true);
            app.btnAddTrack.Text = 'Add...';
            app.tblCurrentTracks = uitable(app.UIFigure);
            app.tblCurrentTracks.ColumnName = '';
            app.tblCurrentTracks.SelectionType = 'row';
            app.tblCurrentTracks.SelectionChangedFcn = createCallbackFcn(app, @tblCurrentTracksSelectionChanged, true);
            app.tblCurrentTracks.Multiselect = 'off';
            app.btnCalculate = uibutton(app.UIFigure, 'push');
            app.btnCalculate.ButtonPushedFcn = createCallbackFcn(app, @btnCalculateButtonPushed, true);
            app.btnCalculate.Text = 'Calculate';
            app.bgpPlotData = uibuttongroup(app.UIFigure);
            app.bgpPlotData.SelectionChangedFcn = createCallbackFcn(app, @bgpPlotDataSelectionChanged, true);
            app.bgpPlotData.Title = 'Plot data';
            app.optMPMSD = uiradiobutton(app.bgpPlotData);
            app.optMPMSD.Text = 'MSD';
            app.optMPMSD.Value = true;
            app.optGSER = uiradiobutton(app.bgpPlotData);
            app.optGSER.Text = 'Modulus';
            app.optAlpha_2 = uiradiobutton(app.bgpPlotData);
            app.optAlpha_2.Text = '\it\alpha\rm_2';
            app.optAlpha_2.Interpreter = 'tex';
            app.optVanHove = uiradiobutton(app.bgpPlotData);
            app.optVanHove.Text = 'van Hove';
            app.optChi4 = uiradiobutton(app.bgpPlotData);
            app.optChi4.Enable = 'off';
            app.optChi4.Text = '\it\chi\rm_4';
            app.optChi4.Interpreter = 'tex';
            app.btnChi_4 = uibutton(app.bgpPlotData, 'push');
            app.btnChi_4.ButtonPushedFcn = createCallbackFcn(app, @btnChi_4ButtonPushed, true);
            app.btnChi_4.Enable = 'off';
            app.btnChi_4.Interpreter = 'tex';
            app.btnChi_4.Text = '\it\chi\rm_4';
            app.LagtimesLabel = uilabel(app.UIFigure);
            app.LagtimesLabel.Text = 'Lag time (s):';
            app.sldLagTime = uislider(app.UIFigure);
            app.sldLagTime.ValueChangedFcn = createCallbackFcn(app, @sldLagTimeValueChanged, true);
            app.sldLagTime.MinorTicks = [0 2.5 5 7.5 10 12.5 15 17.5 20 22.5 25 27.5 30 32.5 35 37.5 40 42.5 45 47.5 50 52.5 55 57.5 60 62.5 65 67.5 70 72.5 75 77.5 80 82.5 85 87.5 90 92.5 95 97.5 100];
            app.btnLucy = uibutton(app.UIFigure, 'push');
            app.btnLucy.ButtonPushedFcn = createCallbackFcn(app, @btnLucyButtonPushed, true);
            app.btnLucy.Text = 'Lucy';
            app.btnRemove = uibutton(app.UIFigure, 'push');
            app.btnRemove.ButtonPushedFcn = createCallbackFcn(app, @btnRemoveButtonPushed, true);
            app.btnRemove.Text = 'Clear';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save...';
            app.TrackfilesmatLabel = uilabel(app.UIFigure);
            app.TrackfilesmatLabel.Text = {'Track files'; '(*.mat):'};
            app.lblStatus = uilabel(app.UIFigure);
            app.lblStatus.Interpreter = 'tex';
            app.lblStatus.Text = '';
            app.btnGSEROpt = uibutton(app.UIFigure, 'push');
            app.btnGSEROpt.ButtonPushedFcn = createCallbackFcn(app, @btnGSEROptButtonPushed, true);
            app.btnGSEROpt.Text = 'GSER';
            app.btnTrajectories = uibutton(app.UIFigure, 'push');
            app.btnTrajectories.ButtonPushedFcn = createCallbackFcn(app, @btnTrajectoriesPushed, true);
            app.btnTrajectories.Text = 'Traj. excl.';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = track_anal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end